classdef flyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofFlashesEditFieldLabel  matlab.ui.control.Label
        NumberofFlashesEditField       matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.Button
        FlashDurationEditFieldLabel    matlab.ui.control.Label
        FlashDurationEditField         matlab.ui.control.NumericEditField
        BreakDurationEditFieldLabel    matlab.ui.control.Label
        BreakDurationEditField         matlab.ui.control.NumericEditField
        FlashStrengthEditFieldLabel    matlab.ui.control.Label
        FlashStrengthEditField         matlab.ui.control.NumericEditField
        InitialWaitTimeEditFieldLabel  matlab.ui.control.Label
        InitialWaitTimeEditField       matlab.ui.control.NumericEditField
        LightParamsLabel               matlab.ui.control.Label
        DetectionParamsLabel           matlab.ui.control.Label
        MinAreaEditFieldLabel          matlab.ui.control.Label
        MinAreaEditField               matlab.ui.control.NumericEditField
        MaxAreaEditFieldLabel          matlab.ui.control.Label
        MaxAreaEditField               matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        FileParamsLabel                matlab.ui.control.Label
        FileDurationEditFieldLabel     matlab.ui.control.Label
        FileDurationEditField          matlab.ui.control.NumericEditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        TimeOutEditFieldLabel          matlab.ui.control.Label
        TimeOutEditField               matlab.ui.control.NumericEditField
        FPSEditFieldLabel              matlab.ui.control.Label
        FPSEditField                   matlab.ui.control.NumericEditField
        RunTimeEditFieldLabel          matlab.ui.control.Label
        RunTimeEditField               matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: NumberofFlashesEditField
        function NumberofFlashesEditFieldValueChanged(app, event)
            global numF;
            numF = app.NumberofFlashesEditField.Value;            
        end

        % Value changed function: InitialWaitTimeEditField
        function InitialWaitTimeEditFieldValueChanged(app, event)
            global lWt;
            lWt = app.InitialWaitTimeEditField.Value;            
        end

        % Value changed function: BreakDurationEditField
        function BreakDurationEditFieldValueChanged(app, event)
            global lBtw;
            lBtw = app.BreakDurationEditField.Value;            
        end

        % Value changed function: FlashStrengthEditField
        function FlashStrengthEditFieldValueChanged(app, event)
            global lStr;
            lStr = app.FlashStrengthEditField.Value;           
        end

        % Value changed function: FlashDurationEditField
        function FlashDurationEditFieldValueChanged(app, event)
            global lDur;
            lDur = app.FlashDurationEditField.Value;           
        end

        % Value changed function: MinAreaEditField
        function MinAreaEditFieldValueChanged(app, event)
            global minA;
            minA = app.MinAreaEditField.Value;            
        end

        % Value changed function: MaxAreaEditField
        function MaxAreaEditFieldValueChanged(app, event)
            global maxA;
            maxA = app.MaxAreaEditField.Value;            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            global thr;
            thr = app.ThresholdEditField.Value;            
        end

        % Value changed function: FileDurationEditField
        function FileDurationEditFieldValueChanged(app, event)
            global dur;
            dur = app.FileDurationEditField.Value;            
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            global vidName;
            vidName = app.FileNameEditField.Value;          
        end

        % Value changed function: TimeOutEditField
        function TimeOutEditFieldValueChanged(app, event)
            global tOut;
            tOut = app.TimeOutEditField.Value;            
        end

        % Value changed function: FPSEditField
        function FPSEditFieldValueChanged(app, event)
            global fps;
            fps = app.FPSEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global thr;
            global minA;
            global maxA;
            global fps;
            global dur;
            global tOut;
            global lWt;
            global lBtw;
            global lDur;
            global lStr;
            global numF;
            global time;
            global vidName;
            
            a   = tic;
            num = 1;
            b   = toc(a);
            while b > time
                writeDigitalPin(ard, 'D5', 1);
                name = [vidName num2str(num)];
                
                vidName = name; 

%                 thr  = 50;      %threshold (the lower, the less inclusive)
%                 minA = 10000;   %minimum area
%                 maxA = 500000;  %maximum area
%                 fps  = 30;      %frames per second
%                 dur  = 10;      %duration of vid file
%                 tOut = 60;      %time before program gives up in seconds
%                 lWt  = 0.0;     %how long before initial flash (after detection)
%                 lBtw = .5;      %how long between flashes
%                 lDur = .5;      %how long flash lasts
%                 lStr = 4.5;     %streangth of light (lower is stronger)
%                 numF = 4;       %number of flashes
                             
                aviObject           = VideoWriter(vidName);  % Create a new AVI file
                aviObject.FrameRate = fps;
                open(aviObject);
                
                ard                  = arduino('com7', 'uno');
                vid                  = videoinput('pointgrey', 1);
                vid.FramesPerTrigger = Inf;
            
                %Beginning to create arrays to keep track of frames where light is
                %triggered and frames where light is turned off
                
                lFrames = [;];
                %dFrames = [];
                   
                check1 = true;   %starting image aquisition
            
                
                %--------------------------------------------------------------------
                
                
                writeDigitalPin(ard, 'D5', 1);
                
                preview(vid);
                  
                a = tic;
                b = toc(a);
                
                
                while b <= tOut
                    im  = getsnapshot(vid);
                    im  = imresize(im, 0.33);
                    
                    tm  = imgaussfilt(im, 12);
                    tIm = tm < thr;
            
                    fIm = bwareafilt(tIm,[minA maxA]);
                    fIm = imresize(fIm, .5);
                    props = regionprops(fIm, 'Area', 'Perimeter','PixelIdxList');
                    
                    c = tic;     %baseline for timer d (when object is detected)
                    
                    if size(props) > 0
                        if check1
                            check1 = false;
                            start(vid);             %starts recording video after detection
                        
                        else
                            while true              %fly is detected and rec started
            
                                %disp('yus');
            
                                d = round(toc(c));  %checks timer since object was detected
            
                                disp(d);
                                
                                if mod(d, lWt)   %initial wait time over
                                    %disp('butt');
                                    count = 0;
                                    while d < dur                             %once waitime over
                                        s          = size(lFrames,1) + 1;     %set variables for
                                        m          = 1;                       %light frames
                                        
                                        if count < numF
                                            
                                            writePWMVoltage(ard, 'D5', lStr); %lights on
                                            disp('light on');
                                            fa = vid.FramesAcquired;
                                            lFrames(s,m) = fa;  
                                            pause(lDur);
                                            m = 2;
            
                                            writeDigitalPin(ard, 'D5', 1);    %lights off
                                            disp('light off');
                                            fa = vid.FramesAcquired;
                                            lFrames(s,m) = fa;
                                            disp(fa);
                                            pause(lBtw)
                                            count = count + 1;
                                        end
                                        
                                        d = round(toc(c));   %checks timer since object was detected
                                    end
                                    break          %break out of while true loop
                                
                                elseif d < lWt   %initial wait time not reached
                                    %disp('not yet'); 
                                end
                                
                            end
                            break    %break out of overall loop when vid is over
                        end
                        
                    else
                        b = toc(a);  %refreshes timer that times out program
                    end
                    
                    writeDigitalPin(ard, 'D5', 1);  %this is if nothing's been detected yet
                    %disp('nah');
                end
                
                
                frames = getdata(vid);
            
                for f = 1:size(frames, 4)
                    for grp = 1:size(lFrames)
                        if f > lFrames(grp, 1) && f < lFrames(grp, 2)
                            
                            frames(931:950,1251:1270,:,f) = 255*ones(20,20);
                            frames(936:945,1256:1265,:,f) = zeros(10,10);
                        end
                    end 
                end
                
                writeVideo(aviObject, frames);
                close(aviObject);
                delete(vid);
                
                num =+ 1;
                b = toc(a);
                pause()
            end    
            
        end

        % Value changed function: RunTimeEditField
        function RunTimeEditFieldValueChanged(app, event)
            global time;
            time = app.RunTimeEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFlashesEditFieldLabel.Text = 'Number of Flashes';
            app.NumberofFlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofFlashesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofFlashesEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FlashDurationEditFieldLabel = uilabel(app.UIFigure);
            app.FlashDurationEditFieldLabel.Text = 'Flash Duration';
            app.FlashDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlashDurationEditField.ValueChangedFcn = createCallbackFcn(app, @FlashDurationEditFieldValueChanged, true);
            app.BreakDurationEditFieldLabel = uilabel(app.UIFigure);
            app.BreakDurationEditFieldLabel.Text = 'Break Duration';
            app.BreakDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakDurationEditField.ValueChangedFcn = createCallbackFcn(app, @BreakDurationEditFieldValueChanged, true);
            app.FlashStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.FlashStrengthEditFieldLabel.Text = 'Flash Strength';
            app.FlashStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlashStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @FlashStrengthEditFieldValueChanged, true);
            app.InitialWaitTimeEditFieldLabel = uilabel(app.UIFigure);
            app.InitialWaitTimeEditFieldLabel.Text = 'Initial Wait Time';
            app.InitialWaitTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialWaitTimeEditField.ValueChangedFcn = createCallbackFcn(app, @InitialWaitTimeEditFieldValueChanged, true);
            app.LightParamsLabel = uilabel(app.UIFigure);
            app.LightParamsLabel.Text = 'Light Params';
            app.DetectionParamsLabel = uilabel(app.UIFigure);
            app.DetectionParamsLabel.Text = 'Detection Params';
            app.MinAreaEditFieldLabel = uilabel(app.UIFigure);
            app.MinAreaEditFieldLabel.Text = 'Min Area';
            app.MinAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinAreaEditFieldValueChanged, true);
            app.MaxAreaEditFieldLabel = uilabel(app.UIFigure);
            app.MaxAreaEditFieldLabel.Text = 'Max Area';
            app.MaxAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MaxAreaEditFieldValueChanged, true);
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.FileParamsLabel = uilabel(app.UIFigure);
            app.FileParamsLabel.Text = 'File Params';
            app.FileDurationEditFieldLabel = uilabel(app.UIFigure);
            app.FileDurationEditFieldLabel.Text = 'File Duration';
            app.FileDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FileDurationEditField.ValueChangedFcn = createCallbackFcn(app, @FileDurationEditFieldValueChanged, true);
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.TimeOutEditFieldLabel = uilabel(app.UIFigure);
            app.TimeOutEditFieldLabel.Text = 'Time Out';
            app.TimeOutEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeOutEditField.ValueChangedFcn = createCallbackFcn(app, @TimeOutEditFieldValueChanged, true);
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.FPSEditField.ValueChangedFcn = createCallbackFcn(app, @FPSEditFieldValueChanged, true);
            app.RunTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RunTimeEditFieldLabel.Text = 'Run Time';
            app.RunTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RunTimeEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = flyApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end